classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ConsoleLogTextArea       matlab.ui.control.TextArea
        ConsoleLogTextAreaLabel  matlab.ui.control.Label
        TareVoltageButton        matlab.ui.control.Button
        TareCurrentButton        matlab.ui.control.Button
        StartProgramButton       matlab.ui.control.Button
        SetPointEditField        matlab.ui.control.NumericEditField
        SetPointLabel            matlab.ui.control.Label
        LiftforceEditField       matlab.ui.control.NumericEditField
        LiftforceEditFieldLabel  matlab.ui.control.Label
        MotorEditField           matlab.ui.control.NumericEditField
        MotorLabel               matlab.ui.control.Label
        TareScaleButton          matlab.ui.control.Button
        StartStopMotorButton     matlab.ui.control.Button
        plot                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        userInput = ''% Description
    end
    
    methods (Access = public)
        
        function keyPressCallback(app, event)
            % Use the global variable 'userInput' inside the callback function
            % Get the pressed key
            key = event.Key;
        
            % Handle the user input
            if ischar(key) && length(key) == 1 && isletter(key) && ~strcmp(key, app.userInput)
                app.userInput = key;
                fprintf(key)
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             ax=axes('parent',app.UIFigure);
            ax = app.plot;
            h1 = animatedline(ax, 'Color','b','LineWidth',2, 'MaximumNumPoints',500); 
            h2 = animatedline(ax, 'Color','r','LineWidth',2, 'MaximumNumPoints',500); 
            h3 = animatedline(ax, 'Color','g','LineWidth',2, 'MaximumNumPoints',500); 
            % grid on;
            
            s = serialport('COM14', 9600);
            configureTerminator(s, "CR/LF");
            
            % Use this code for real-time data vizualisation (plot angle vs time)
            clc; close all; flush(s);
            time = 0;
            while 1
                time = time + 50;
%                 write to serial
                if (~strcmp(app.userInput, ''))
                    disp(app.userInput);
                    writeline(s, app.userInput);
                end
                app.userInput = '';
                
                %read from serial communication
                %do NOT add a semicolon so we can see its outputs via the command window
                string = readline(s);
                if (size(string) == 0)
                    continue;
                end
                disp(string)
                app.ConsoleLogTextArea.Value = string;
                %scans the string for inputs: %f 
                %double %% means one % in the string
                data = sscanf(string, "Load_cell output val: %f\nsetpoint: %f\nmotor output: %f%%\ntime: %f\n");
                %make sure the data is correct i.e. 4 outputs is extracted from string
                if (size(data) < 4) 
                    continue;
                end
                %assign the variables
                loadcell = data(1);
                setPoint = data(2);
                motor = data(3);
                time = data(4);
                
                %add fields to app value
                app.MotorEditField.Value = motor;
                app.SetPointEditField.Value = setPoint;
                app.LiftforceEditField.Value = loadcell;
                
                %set xlim to move our graph horizontally
                xlim(ax, [max(0, time/1000 - 10*1.0), time/1000 + 10*0.2]);
                %set ylim to resize our graph upward
                ylim(ax, [-10, min(250, round(setPoint/40)*40 + 40)]);
                %add points to the corresponding lines
                addpoints(h1, time/1000, loadcell)  
                addpoints(h2, time/1000, setPoint)
                addpoints(h3, time/1000, motor)
%                 drawnow limitrate
                % writematrix([loadcell time/1000],'loadcell.xls','WriteMode','append')
            end
            
            % screen_property = get(0,'screensize');
            % set(gcf, "OuterPosition", [0, screen_property(4)/2, ...
            % screen_property(3)/2, screen_property(4)/2])

            % keyPressCallback(app, event)
        end

        % Key press function: UIFigure
        function plotButtonDown(app, event)
            keyPressCallback(app, event)
        end

        % Button pushed function: StartStopMotorButton
        function StartStopMotorButtonPushed(app, event)
            app.userInput = "s";
            fprintf(app.userInput);
        end

        % Button pushed function: StartProgramButton
        function startbuttonCallback(app, event)
            app.userInput = 'z';
        end

        % Button pushed function: TareScaleButton
        function tButtonCallback(app, event)
            app.userInput = 't';
        end

        % Button pushed function: TareCurrentButton
        function tareCurrentCallback(app, event)
            app.userInput = 'y';
        end

        % Button pushed function: TareVoltageButton
        function tareVoltageCallback(app, event)
            app.userInput = 'u';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @plotButtonDown, true);
            app.plot = uiaxes(app.UIFigure);
            title(app.plot, 'Title')
            xlabel(app.plot, 'X')
            ylabel(app.plot, 'Y')
            zlabel(app.plot, 'Z')
            app.StartStopMotorButton = uibutton(app.UIFigure, 'push');
            app.StartStopMotorButton.ButtonPushedFcn = createCallbackFcn(app, @StartStopMotorButtonPushed, true);
            app.StartStopMotorButton.Text = 'Start/Stop Motor';
            app.TareScaleButton = uibutton(app.UIFigure, 'push');
            app.TareScaleButton.ButtonPushedFcn = createCallbackFcn(app, @tButtonCallback, true);
            app.TareScaleButton.Text = 'Tare Scale';
            app.MotorLabel = uilabel(app.UIFigure);
            app.MotorLabel.Text = 'Motor %';
            app.MotorEditField = uieditfield(app.UIFigure, 'numeric');
            app.LiftforceEditFieldLabel = uilabel(app.UIFigure);
            app.LiftforceEditFieldLabel.Text = 'Lift force';
            app.LiftforceEditField = uieditfield(app.UIFigure, 'numeric');
            app.SetPointLabel = uilabel(app.UIFigure);
            app.SetPointLabel.Text = 'Set Point';
            app.SetPointEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartProgramButton = uibutton(app.UIFigure, 'push');
            app.StartProgramButton.ButtonPushedFcn = createCallbackFcn(app, @startbuttonCallback, true);
            app.StartProgramButton.Text = 'Start Program';
            app.TareCurrentButton = uibutton(app.UIFigure, 'push');
            app.TareCurrentButton.ButtonPushedFcn = createCallbackFcn(app, @tareCurrentCallback, true);
            app.TareCurrentButton.Text = 'Tare Current';
            app.TareVoltageButton = uibutton(app.UIFigure, 'push');
            app.TareVoltageButton.ButtonPushedFcn = createCallbackFcn(app, @tareVoltageCallback, true);
            app.TareVoltageButton.Text = 'Tare Voltage';
            app.ConsoleLogTextAreaLabel = uilabel(app.UIFigure);
            app.ConsoleLogTextAreaLabel.Text = 'Console Log';
            app.ConsoleLogTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end